classdef GUI_fordeepometrymac2_PART4m14vvllRECENT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavetemporarydirectoryCheckBox  matlab.ui.control.CheckBox
        TemporaryfolderEditField_4      matlab.ui.control.EditField
        TemporaryfolderEditField_4Label  matlab.ui.control.Label
        RetrieveButton_3                matlab.ui.control.Button
        TemporaryfolderEditField_3      matlab.ui.control.EditField
        TemporaryfolderEditField_3Label  matlab.ui.control.Label
        TargetClassificationsDropDown_3  matlab.ui.control.DropDown
        TargetClassificationsDropDown_3Label  matlab.ui.control.Label
        UnannotateddataCheckBox         matlab.ui.control.CheckBox
        RetrieveButton_2                matlab.ui.control.Button
        TargetClassificationsDropDown_2  matlab.ui.control.DropDown
        TargetClassificationsDropDown_2Label  matlab.ui.control.Label
        TrainHoldoutEditField           matlab.ui.control.EditField
        TrainHoldoutLabel               matlab.ui.control.Label
        LabelimagesCheckBox             matlab.ui.control.CheckBox
        TemporaryfolderEditField        matlab.ui.control.EditField
        TemporaryfolderEditFieldLabel   matlab.ui.control.Label
        RetrieveButton                  matlab.ui.control.Button
        TargetClassificationsDropDown   matlab.ui.control.DropDown
        TargetClassificationsDropDownLabel  matlab.ui.control.Label
        FrameSizeEditField              matlab.ui.control.NumericEditField
        FrameSizeEditFieldLabel         matlab.ui.control.Label
        Image5_2                        matlab.ui.control.Image
        LearningIterationsEditField     matlab.ui.control.EditField
        LearningIterationsEditFieldLabel  matlab.ui.control.Label
        ChannelsEditField               matlab.ui.control.EditField
        ChannelsLabel                   matlab.ui.control.Label
        ExtractButton                   matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        ModellocationEditField_3        matlab.ui.control.EditField
        ModellocationEditField_3Label   matlab.ui.control.Label
        OutputlocationEditField_7       matlab.ui.control.EditField
        OutputlocationEditField_7Label  matlab.ui.control.Label
        InputlocationEditField_7        matlab.ui.control.EditField
        InputlocationEditField_7Label   matlab.ui.control.Label
        ExtractfeaturestovisualizeButtonGroup  matlab.ui.container.ButtonGroup
        avg_pool_4_4Button              matlab.ui.control.RadioButton
        activation_43_reluButton        matlab.ui.control.RadioButton
        activation_25_reluButton        matlab.ui.control.RadioButton
        Image5                          matlab.ui.control.Image
        PredictButton                   matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        TrainButton                     matlab.ui.control.Button
        ModellocationEditField          matlab.ui.control.EditField
        ModellocationEditFieldLabel     matlab.ui.control.Label
        OutputlocationEditField_5       matlab.ui.control.EditField
        OutputlocationEditField_5Label  matlab.ui.control.Label
        InputlocationEditField_5        matlab.ui.control.EditField
        InputlocationEditField_5Label   matlab.ui.control.Label
        OutputlocationEditField_4       matlab.ui.control.EditField
        OutputlocationEditField_4Label  matlab.ui.control.Label
        InputlocationEditField_4        matlab.ui.control.EditField
        InputlocationEditField_4Label   matlab.ui.control.Label
        ParseButton                     matlab.ui.control.Button
        OutputlocationEditField         matlab.ui.control.EditField
        OutputlocationEditFieldLabel    matlab.ui.control.Label
        InputlocationEditField          matlab.ui.control.EditField
        InputlocationEditFieldLabel     matlab.ui.control.Label
        bFeatureextractionLabel         matlab.ui.control.Label
        aPredictionLabel                matlab.ui.control.Label
        ModeltrainingLabel              matlab.ui.control.Label
        MontageSizeEditField_3          matlab.ui.control.NumericEditField
        MontageSizeEditField_3Label     matlab.ui.control.Label
        PreprocessingLabel              matlab.ui.control.Label
        DeepometryLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)


        function [ image_scaled ] = pad_image_to_correct_size2(app, image_raw, made_up_size)
            
            % find the mean and standard deviation
            sample_top_left = double(reshape(image_raw(1:10,1:10),100,1));   
            mu_top_left = mean(sample_top_left);
            sigma_top_left = sqrt(var(sample_top_left));
            % find the mean and standard deviation
            sample_top_right = double(reshape(image_raw(1:10,end-9:end),100,1));   
            mu_top_right = mean(sample_top_right);
            sigma_top_right = sqrt(var(sample_top_right));
            % find the mean and standard deviation
            sample_bottom_left = double(reshape(image_raw(end-9:end,1:10),100,1));   
            mu_bottom_left = mean(sample_bottom_left);
            sigma_bottom_left = sqrt(var(sample_bottom_left));  
            % find the mean and standard deviation
            sample_bottom_right = double(reshape(image_raw(end-9:end,end-9:end),100,1));   
            mu_bottom_right = mean(sample_bottom_right);
            sigma_bottom_right = sqrt(var(sample_bottom_right));  
            
            % pick the a corner to find mean and std from without debris 
            % i.e. (lowest std)
            [~,minimum_sigma_position]=min([sigma_top_left; sigma_top_right; sigma_bottom_left; sigma_bottom_right ]);
            if minimum_sigma_position==1
               mu=mu_top_left  ;
               sigma=sigma_top_left ;      
            elseif minimum_sigma_position==2
               mu=mu_top_right   ;
               sigma=sigma_top_right ;        
            elseif minimum_sigma_position==3
               mu=mu_bottom_left   ;
               sigma=sigma_bottom_left ; 
            else 
               mu=mu_bottom_right   ;
               sigma=sigma_bottom_right ;      
            end
            
             
            % make an image of the size required
            image_scaled = uint16(abs(round(random('norm',mu,sigma,made_up_size ))));
            
            % if image is bigger than desired size this module cuts it 
            image_raw_cut = cut(image_raw,made_up_size);
             
            % find the index to place old image to postion in the centre of new
            % image
            ind1 = findind(size(image_raw_cut,1),made_up_size);
            ind2 = findind(size(image_raw_cut,2),made_up_size);
            % use indices to place image
            image_scaled(ind1,ind2) = image_raw_cut;
        end


        function [cut_image] = cut(app, im_raw,made_up)
            
            % cuts image on all sides to be desired size
            if size(im_raw,1) > made_up
                shift = round((size(im_raw,1)-made_up)./2);
                im_raw(end-shift+1:end,:)=[];
                im_raw(1:shift,:)=[];
            end
            if size(im_raw,2) > made_up
                shift = round((size(im_raw,2)-made_up)./2);
                im_raw(:,end-shift+1:end)=[];
                im_raw(:,1:shift)=[];
            end
            cut_image = im_raw;  
            
        end


        function data = matReader(app, filename)
            

            d = load(filename);
            f = fields(d);
            data = d.(f{1})(:,:,:);
            data = uint16(data);
            
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ParseButton
        function ParseButtonPushed(app, event)
            original_imageFolder =  app.InputlocationEditField.Value;
            save_new_imageFolder_new = app.OutputlocationEditField.Value;
            channels_holder =  app.ChannelsEditField.Value;
            Frame =  app.FrameSizeEditField.Value;
            
            channels =str2num(channels_holder);
            
            if isempty(app.InputlocationEditField.Value)
                h=errordlg('Please input the location of your images','Error');
                set(h, 'WindowStyle', 'modal');
            end


            if isempty(app.OutputlocationEditField.Value)
                h=errordlg('Please specify the name of the folder you wish to save preprocessed files','Error');
                set(h, 'WindowStyle', 'modal');
                
            end
            
            
            if isempty(app.ChannelsEditField.Value)
                h=errordlg('You should input the channel numbers to be analysed','Error');
                set(h, 'WindowStyle', 'modal');
                
            end
            if isempty(app.FrameSizeEditField.Value)
                h=errordlg('Please input the size of your images','Error');
                set(h, 'WindowStyle', 'modal');
            end


            rootdir = original_imageFolder;
            filelist = dir(fullfile(rootdir, '**\*.*'));
            filelist = filelist(~[filelist.isdir]);


            for k =1:length(filelist)
                namep = filelist(k).name;
                pattern = 'tif';
                pattern2 = 'cif';
                TF(k) = contains(namep,pattern);
                TF2(k) = contains(namep,pattern2);
            end
            
            [a,b] = find(TF>0);
            [a2,b2] = find(TF2>0);
            
            if length(a==0)&&length(a2==0)
                h=errordlg('Tif/cif images not found, check input directory','Error');
                set(h, 'WindowStyle', 'modal');
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Execute if tif images are found
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if length(a)>0
                
                 %Check that specified channels are present
                
                TFChannelcheck = zeros(length(filelist),length(channels));
                for kk = 1:length(channels)
                    for k =1:length(filelist)
                        namep = filelist(k).name;
                        pattern = strcat('Ch',num2str(channels(kk)));
                        TFChannelcheck(k,:) = contains(namep,pattern);
                        
                    end
                end
                
                for j = 1:length(channels)
                    [a,b] = find(TFChannelcheck(:,j)==1);
                    Lens(j) = length(a);
                end
                
                if any(Lens == 0)
                    h=errordlg('Some channels specified are not found, respecify which are to be analysed','Error');
                    set(h, 'WindowStyle', 'modal');
                    
                end
                
                File_listings = struct2cell(filelist);
                File_listings_transposed = File_listings';
                newStr = [];
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Clean data, removing any images with missing channels
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                File_directory = File_listings_transposed(:,2);
                
                files = File_listings_transposed(:,1);
                Total_path = strcat(File_directory,'\',files);
                
                %Find groups of images based on subdirectories
                
                ind = findgroups(File_directory);
                Number_groups = max(ind);
                groups_ = [];
                LChan = length(channels);
                
                
                Total_path2 = Total_path;
                files2 = files;
                File_directory2 = File_directory;
                
                AA = [];
                Holder = [];
                B = [];
                for i = 1:Number_groups
                    [a,b] = find(ind==i);
                    AA = files(a);
                    Holder = File_directory(a);
                    B = split(AA,"Ch");
                    
                    S = length(a);
                    if S == 1
                        B = B';
                    end
                    
                    
                    Names_ = [];
                    ind2 = [];
                    for j = 1:length(a)
                        Names_{j} = B{j,1};
                    end
                    
                    %Group images according to filenames
                    
                    
                    ind2 = findgroups(Names_);
                    
                    
                    %Check to see if images have all specified channels
                    
                    
                    for t = 1:max(ind2)
                        [aa,bb] = find(ind2==t);
                        AA2 = [];
                        Holder2 = [];
                        Overall = [];
                        for k = 1:length(bb)
                            AA2{k} = strcat(B{bb(k),1},'Ch',B{bb(k),2});
                            Holder2{k} = Holder{bb(k)};
                            Overall{k} = strcat(Holder2{k},'\',AA2{k});
                        end
                        
                        for m = 1:LChan
                            v = num2str(channels(m));
                            pattern1 = strcat('Ch',v,'.');
                            TF = contains(AA2,pattern1);
                            [a3,b3] = find(TF==1);


                            if length(a3) ==0
                                for ii =1:length(AA2)
                                    pattern2 = Overall{ii}
                                    Index = find(matches(Total_path,pattern2));
                                    Total_path2{Index} = '0';
                                    files2{Index} = '0';
                                    File_directory2{Index} = '0';
                                end
                                
                                
                            end
                        end
                    end
                    
                    AA = [];
                    Holder = [];
                    B = [];
                end
                
                %Groups of images without a full set of channels are deleted
                %from list
                
                
                AA22 = find(matches(Total_path2,'0'));
                Total_path2(AA22) = [];
                AA22 = find(matches(File_directory2,'0'));
                File_directory2(AA22) = [];
                AA22 = find(matches(files2,'0'));
                files2(AA22) = [];
                File_directory_new1 = File_directory2;
                files_new1 = files2;
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Remove channels not specified from the list of images to be
                %processed
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                File_directory_new = [];
                files_new = [];
                Lchan = length(channels);
                
                
                for k =1:length(files_new1)
                    namep = files_new1{k};
                    TK3 = [];
                    for m = 1:Lchan
                        
                        pattern = string(channels(m));
                        patternfinal = strcat('Ch',pattern,'.');
                        TK3(m) = contains(namep,patternfinal);
                    end
                    [a,b] = find(TK3==1);
                    if length(a)>0
                        files_new{k} = files_new1{k};
                        File_directory_new{k} = File_directory_new1{k};
                        
                    else
                        files_new{k} ='0';
                        File_directory_new{k} = '0';
                        
                    end
                    namep =[];
                end
                
                %Delete all additional channel images from list
                
                AA22 = find(matches(files_new,'0'));
                files_new(AA22) = [];
                files_new = files_new';
                AA33 = find(matches(File_directory_new,'0'));
                File_directory_new(AA33) = [];
                
                newStr = [];
                strtester = [];
                newStr = [];
                tokenNames = [];


                if ~exist(save_new_imageFolder_new, 'dir')
                    mkdir(save_new_imageFolder_new);
                    
                end
                
                
                updated_path = [];
                new_path = [];
                CC = [];
                for i = 1:length(File_directory_new)
                    strtester = File_directory_new{i};
                    expression = original_imageFolder;
                    updated_path{i} = erase(strtester,expression);


                    %Prepare directoryfolders to store images


                    x = save_new_imageFolder_new;
                    lastletter1 = x(end);
                    y = updated_path{i};
                    firstletter2 = y(end);
                    
                    new_path{i} = strcat(save_new_imageFolder_new,updated_path{i});
                    
                    
                    new_path{i}=strrep(new_path{i},'/','\');
                    
                    new_path{i}=strrep(new_path{i},'\\','\');
                    disp(new_path{i})
                    
                    CC{i} = join([File_directory_new{i},'\',files_new{i}]);
                    
                    
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Execute if user wishes to apply cross-validation
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                val = app.TrainHoldoutEditField.Value;
                if val
                    AA = split(val,"/");
                    Valuedata_split = str2num(AA{2});
                else
                    Valuedata_split = 0;
                end
                
                
                M = length(File_directory_new)/length(channels);
                Valuedata_split_prop = floor((Valuedata_split/100)*(length(File_directory_new)/length(channels)));
                
                r = randi([1,M],1,Valuedata_split_prop);
                ind_image = [1:length(channels):length(File_directory_new)];
                rr = ind_image(r);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Pad and crop images according the specs and save in
                % output folder
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                for i = 1:length(File_directory_new)/length(channels)


                    ind_image = [1:length(channels):length(File_directory_new)];
                    Actual_imageNumber = [1:1:length(File_directory_new)/length(channels)];
                    
                    
                    index_used = ind_image(i);
                    image_matrix = [];
                    data = [];
                    channel11 = [];
                    padded_image_channel = [];
                    min_channel = [];
                    max_channel = [];
                    both_channels = [];
                   
                    
                    for mm=1:Lchan
                        name = CC{index_used+(mm-1)};
                        
                        image_matrix{mm}=imread(name);
                        holder1 = image_matrix{mm};
                        data{mm} = uint16(holder1);
                        
                        channel11{mm}=imresize(data{mm},0.9,'bilinear');
                        
                        %Pad or crop images to the correct size for the
                        %network
                        
                        
                        padded_image_channel{mm}=double(pad_image_to_correct_size2(channel11{mm},[Frame Frame])); %Needs to change to size
                        holder = padded_image_channel{mm};
                        min_channel{mm} = double(min(holder(:)));
                        max_channel{mm} = double(max(holder(:)));
                        both_channels(:,:,mm)=((padded_image_channel{mm}-min_channel{mm})/(max_channel{mm}-min_channel{mm}))*255 ;
                        
                        
                    end
                    new=uint8(both_channels(:,:,:));
                    A = string(i);


                    channel = '_channel_all';
                    name = strcat(A,channel);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Split files into training and hold out set if specified
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    
                    if Valuedata_split_prop>0
                        
                        if  ismember(ind_image(i),rr);
                            
                            new_path{ind_image(i)} = strcat(save_new_imageFolder_new,'/Holdout_data',updated_path{ind_image(i)});
                        else
                            new_path{ind_image(i)} = strcat(save_new_imageFolder_new,'/Training_data',updated_path{ind_image(i)});
                        end
                    else
                        new_path{ind_image(i)} = strcat(save_new_imageFolder_new,updated_path{ind_image(i)});
                    end
                    if ~exist(new_path{ind_image(i)}, 'dir')
                        mkdir(new_path{ind_image(i)});
                        
                    end
                    name2 = strcat(new_path{ind_image(i)},'\',files_new{ind_image(i)},name,'.mat');
                    outputFileName = name2 ;
                    save(name2, 'new')


                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Execute if Montaging has been specified
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                val_check = app.TrainHoldoutEditField.Value;


                if app.MontageSizeEditField_3.Value>0
                    clusters = findgroups(File_directory_new);
                    AA = unique(clusters);
                    ind_image = [1:length(channels):length(File_directory_new)];
                    
                    for j = 1:max(AA)
                        
                        
                        [a,b] = find(clusters == j);
                        Files_ingroup = [];
                        phenotype_directory_info = File_directory_new{b(1)};
                        saving_info = new_path{b(1)};
                        for m = 1:length(b)
                            Files_ingroup{m} = files_new{b(m)};
                        end
                        
                        
                        [~,ndx] = natsortfiles(Files_ingroup);
                        RFilesupdate = [];
                        for jj = 1:length(ndx);
                            RFilesupdate{jj} = Files_ingroup{ndx(jj)};
                        end
                        %
                        SIZE_files = length(RFilesupdate);
                        
                        
                        %determine number of images per tile and remaining images to be placed
                        %on black background
                        %
                        image_count = length(RFilesupdate);
                        
                        montage_size = app.MontageSizeEditField_3.Value; %%%%%%%% string
                        montage_chunks = montage_size^2;
                        
                        n_chunks =floor((image_count)/length(channels));
                        image_holder = [];
                        extracted = [];
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %When number of images is greater or equal to required
                        %number for one tile, they should be stitched together
                        %in the following way
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        if n_chunks>=montage_chunks
                            
                            n_chunks;
                            montage_chunks;
                            Number_of_montage = floor(n_chunks/montage_chunks);
                          
                            Remaining_images = n_chunks-(Number_of_montage*montage_chunks);
                            chunk_size = montage_chunks;
                            Overall_size = image_count;
                            
                            for m = 1:length(channels)
                                namep =[];
                                TK3 = [];
                                for k =1:length(RFilesupdate)
                                    namep = RFilesupdate{k};
                                    pattern = string(channels(m));
                                    patternfinal = strcat('Ch',pattern,'.');
                                    TK3(k) = contains(namep,patternfinal);
                                end
                                
                                [aaa,bbb] = find(TK3>0);
                                disp(TK3(120));
                                disp(RFilesupdate{2});
                                disp(bbb);
                                BBB = 10/length(bbb);
                                extracted = RFilesupdate(bbb);
                                extracted{1};
                                image_number = 0;
                                vv = 0;
                                
                                %Create tiles of stitches images
                                
                                for l = 1:Number_of_montage
                                    
                                    all_images = [];
                                    chunk_size;
                                    Number_of_montage;
                                    for s = 1:chunk_size
                                        value = s+vv;
                                        files_to_Mont = strcat(phenotype_directory_info,'\',extracted{value});
                                        
                                        image_matrix=imread(files_to_Mont);
                                        holder1 = image_matrix;
                                        data = holder1;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])))
                                        all_images = cat(3,all_images,padded_image_channel);
                                        
                                    end
                                    
                                    images_montage = montage(all_images);
                                    
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(m)));
                                    Montage_no = string(l);
                                    
                                    
                                    ImFileOut = strcat(saving_info,'\','Montage_images','\');
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');
                                    
                                    
                                    image_number = chunk_size+image_number;
                                    vv = value;
                                    
                                end
                                all_images = [];
                                if Remaining_images >0
                                    start = Number_of_montage*montage_chunks;
                                    for t = start+1:(start+Remaining_images-1)
                                        
                                        files_to_Mont = strcat(phenotype_directory_info,'\',extracted{t});
                                        
                                        image_matrix=imread(files_to_Mont);


                                        holder1 = image_matrix;
                                        data = holder1;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])));
                                        all_images = cat(3,all_images,padded_image_channel);


                                    end
                                    
                                    images_montage = montage(all_images, 'Size', [montage_size montage_size]);
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(m)));
                                    Montage_no = string(l+1);
                                    
                                    
                                    ImFileOut = strcat(saving_info,'\','Montage_images','\');
                                    
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');


                                end
                                
                            end
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       %When number of files is less than those required
                       %for a tile
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            
                        elseif n_chunks<montage_chunks
                            
                            
                            Number_of_montage = 1;
                            chunk_size = n_chunks;
                            if chunk_size ==0
                                chunk_size = 1;
                            end
                            
                            namep =[];
                            TK4 = []
                            extracted = [];
                            
                            for m = 1:length(channels)
                                for k =1:length(RFilesupdate)
                                    namep = RFilesupdate{k};
                                    pattern = string(channels(m));
                                    patternfinal = strcat('Ch',pattern,'.');
                                    TK4(k) = contains(namep,patternfinal);
                                end
                                [aaa,bbb] = find(TK4>0);
                                
                                extracted = RFilesupdate(bbb);
                                vv = 0;
                                for l = 1:Number_of_montage
                                    
                                    all_images = [];
                                    for s = 1:chunk_size
                                        value = s+vv;
                                        files_to_Mont = strcat(phenotype_directory_info,'\',extracted{value});
                                        
                                        image_matrix=imread(files_to_Mont);
                                        holder1 = image_matrix;
                                        
                                        data = holder1;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])));
                                        all_images = cat(3,all_images,padded_image_channel);
                                    end
                                    
                                    images_montage = montage(all_images,'Size', [montage_size montage_size]);
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(m)));
                                    Montage_no = string(l);
                                    
                                    
                                    ImFileOut = strcat(saving_info,'\','Montage_images','\');
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    disp(ImfileOutfinal)
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');
                                end
                            end
                        end
                    end
                end
            else
                filelist = dir(fullfile(rootdir, '**\*.*'));
                filelist = filelist(~[filelist.isdir]);
                File_listings = struct2cell(filelist);
                File_listings_transposed = File_listings';
                newStr = [];
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %If Cif images are found in specified directory
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %Add bioformats to the MATLAB search path
                holder_fold = pwd;
                
                pathtobio = 'bfmatlab';
                addpath(pathtobio)
                
                
                File_listings = struct2cell(filelist);
                File_listings_transposed = File_listings';
                newStr = [];


                File_directory = File_listings_transposed(:,2);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Search directory structure to find classes to use for each
                %image
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                updated_path = [];
                new_path = [];
                CC = [];
                for i = 1:length(File_directory)
                    strtester = File_directory{i};
                    expression = original_imageFolder;
                    updated_path{i} = erase(strtester,expression);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Create the folder if it doesn't exist already.
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    %Prepare directoryfolders to store images


                    x = save_new_imageFolder_new;
                    lastletter1 = x(end);
                    y = updated_path{i};
                    firstletter2 = y(end);
                    
                    
                    new_path{i} = strcat(save_new_imageFolder_new,updated_path{i});


                    new_path{i}=strrep(new_path{i},'/','\');
                    
                    new_path{i}=strrep(new_path{i},'\\','\');
                    disp(new_path{i})
                end


                %Isolate images that belong to each class
                
                CC = [];
                
                % Get images from each class
                
                for i = 1:size(File_listings_transposed,1)
                    CC{i} = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);

                    
                    %Use bioformats to read .CIF files
                    Data21 = bfopen(CC{i});
                    Chan_check = size(Data21{1,1});
                    index_chan = [1:1:Chan_check];
                    v = intersect(index_chan,channels);
                    len_v = length(v);
                    if len_v<length(channels)
                        h=errordlg('Respecify channels to be analysed, some not found','Error');
                        set(h, 'WindowStyle', 'modal');
                    end

                    
                    save_new_imageFolder_pheno = strcat(new_path{i},'\')
                
                    Overall_size = length(Data21)
                    nums = [1:2:Overall_size-1];
                    val = app.TrainHoldoutEditField.Value;
                    if val
                        AA = split(val,"/");
                        Valuedata_split = str2num(AA{2});
                    else
                        Valuedata_split = 0;
                    end
                    
                    
                    M = length(Data21)/2;
                    Valuedata_split_prop = floor((Valuedata_split/100).*M);
                    
                    r = randi([1,M],1,Valuedata_split_prop);


                    for j = 1:(length(Data21)/2)
                        both_channels = [];
                        data = [];
                        channel11 = [];
                        padded_image_channel = [];
                        min_channel=[];
                        max_channel = [];
                        for k = 1:length(channels)
                            p = channels(k);
                            image_current = Data21{nums(j),1}{p,1}
                            data{k} = uint16(image_current);
                            image_name{j}=join(File_listings_transposed{i,1},num2str(j))
                            %Pad or crop images to appropriate size
                            %for network
                            channel11{k}=imresize(data{k},0.9,'bilinear');
                            padded_image_channel{k}=double(pad_image_to_correct_size2(channel11{k},[Frame Frame]));
                            holder = padded_image_channel{k};
                            min_channel{k} = double(min(holder(:)));
                            max_channel{k} = double(max(holder(:)));
                            both_channels(:,:,k)=((padded_image_channel{k}-min_channel{k})/(max_channel{k}-min_channel{k}))*255 ;
                            
                        end
                        
                        if Valuedata_split_prop>0
                            
                            if  ismember(j,r);
                                
                                save_new_imageFolder_pheno = strcat(save_new_imageFolder_new,'/Holdout_data',updated_path{i});
                            else
                                save_new_imageFolder_pheno = strcat(save_new_imageFolder_new,'/Training_data',updated_path{i});
                            end
                        else
                            save_new_imageFolder_pheno = strcat(save_new_imageFolder_new,updated_path{i});
                        end
                        if ~exist(save_new_imageFolder_pheno, 'dir')
                            mkdir(save_new_imageFolder_pheno);
                            
                        end
                        new=uint8(both_channels(:,:,:));
                        A = string(j);
                        channel = '_channel_all';
                        name = strcat(A,channel);
                        name2 = strcat(save_new_imageFolder_pheno,'\',File_listings_transposed{i,1},'_',name,'.mat');
                        outputFileName = name2 ;
                        save(name2, 'new')
                        
                        
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %If Montaging is required
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    if app.MontageSizeEditField_3.Value>0
                        Data_montage = bfopen(CC{i});
                        omeMeta = Data_montage{1,4};
                        image_count = omeMeta.getImageCount;
                        
                        %Determine number of images required for
                        %each tile and number of images present
                        montage_size = app.MontageSizeEditField_3.Value;
                        montage_chunks = montage_size^2;
                        
                        n_chunks =(image_count/2);
                        image_holder = [];
                        
                        %If number of images is less than or equal
                        %to number required per tile
                        
                        if n_chunks>=montage_chunks
                            Number_of_montage = floor(n_chunks/montage_chunks);
                            
                            Remaining_images = n_chunks-(Number_of_montage*montage_chunks);
                            chunk_size = montage_chunks;
                            Overall_size = image_count;
                            nums = [1:2:Overall_size];
                            
                            for m = 1:length(channels)
                                image_number = 0;
                                for l = 1:Number_of_montage
                                    
                                    all_images = [];
                                    
                                    
                                    %Stitch images together
                                    
                                    for s = 1:chunk_size
                                        image_current = Data_montage{nums(image_number+s),1}{channels(m),1};
                                        data = image_current;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])));
                                        all_images = cat(3,all_images,padded_image_channel);
                                        
                                        
                                    end
                                    
                                    images_montage = montage(all_images);
                                    
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(m)));
                                    Montage_no = string(l);
                                    
                                    
                                    ImFileOut = strcat(new_path{i},'\','Montage_images,','\');
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');
                                    
                                    
                                    image_number = chunk_size+image_number;
                                    
                                    
                                end
                                all_images = [];
                                
                                %Place any remaining images of
                                %black background
                                
                                if Remaining_images >0
                                    start = Number_of_montage*montage_chunks;
                                    for t = start+1:(start+Remaining_images-1)
                                        
                                        
                                        image_current = Data_montage{nums(t),1}{m,1};
                                        data = image_current;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])));
                                        all_images = cat(3,all_images,padded_image_channel);
                                        
                                        
                                    end
                                    
                                    images_montage = montage(all_images, 'Size', [montage_size montage_size]);
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(m)));
                                    Montage_no = string(l+1);
                                    
                                    
                                    ImFileOut = strcat(new_path{i},'\','Montage_images,','\');
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');


                                end
                                
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %If number of images is less than
                            %number required per tile
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            
                        elseif n_chunks<montage_chunks
                            
                            Number_of_montage = 1;
                            
                            
                            chunk_size = n_chunks;
                            
                            
                            nums = [1:2:Overall_size-1];
                            
                            for h = 1:length(channels)
                                
                                for l = 1:Number_of_montage
                                    
                                    all_images = [];
                                    for s = 1:chunk_size
                                        image_current = Data_montage{nums(s),1}{h,1};
                                        data = image_current;
                                        
                                        image_holder=imresize(data,0.9,'bilinear');
                                        padded_image_channel=uint8(double(pad_image_to_correct_size2(image_holder,[Frame Frame])));
                                        all_images = cat(3,all_images,padded_image_channel);
                                        
                                        
                                    end
                                    
                                    images_montage = montage(all_images,'Size', [montage_size montage_size]);
                                    montage_IM=images_montage.CData;
                                    name_channel = strcat('Channel_',string(channels(h)));
                                    Montage_no = string(l);
                                    
                                    
                                    ImFileOut = strcat(new_path{i},'\','Montage_images,','\');
                                    if ~exist(ImFileOut, 'dir')
                                        mkdir(ImFileOut);
                                    end
                                    
                                    nameoffile = strcat(name_channel,'Montage_',Montage_no,'.tif');
                                    ImfileOutfinal = strcat(ImFileOut,nameoffile);
                                    
                                    imwrite(montage_IM,ImfileOutfinal,'tif');
                                end
                            end
                        end
                    end
                end
            end


        end

        % Callback function
        function Parse_evaluateButtonPushed(app, event)
      
        end

        % Callback function
        function Parse_predictButtonPushed(app, event)
         
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
           
            image_directory =  string(app.InputlocationEditField_4.Value);
            temporary_location = string(app.TemporaryfolderEditField.Value);
            output_directory = string(app.OutputlocationEditField_4.Value);
            channel_holder =  string(app.ChannelsEditField.Value);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Making temporary directory to store reorganised images based
            %on class
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            Groupingtouse = app.TargetClassificationsDropDown.Value;
            Groupingtouse = string(Groupingtouse);
            disp(Groupingtouse);
            
            if ~exist( temporary_location, 'dir')
                mkdir( temporary_location);
                
            end
            
            if isempty(app.InputlocationEditField_4.Value)
                h=errordlg('Please input the location of your .mat files','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            
            if isempty(app.TemporaryfolderEditField.Value)
                h=errordlg('Please state the location to store a temporary folder','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.TemporaryfolderEditField.Value)
                h=errordlg('Please state the location to store a temporary folder','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            
            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = image_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            newStr = extractAfter(Groupingtouse,":");
            disp(newStr)
            newStrfirst = extractBefore(Groupingtouse,":");
            
            
            MM = split(newStr,',');
            newStr2 = strip(MM);
            newStr2(newStr2=="")=[];


            Classification_folder = strcat(temporary_location,'\','Current_Classifications_',newStrfirst);
            
            
            Classification_folder=strrep(Classification_folder,'\\','\');
            Classification_folder  = string(Classification_folder );
            
            
            if ~exist(Classification_folder, 'dir')
                mkdir(Classification_folder);
                
            end
            disp(newStr2)
            
            for j = 1:length(newStr2)
                Classification_folder_subfolder = strcat(Classification_folder,'\',newStr2{j});
                
                if ~exist(Classification_folder_subfolder, 'dir')
                    mkdir(Classification_folder_subfolder);
                end
                
            end
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            AA = [];
            File_holding = [];
            testingpattern2 = strcat('\Current_Classifications_');
            testingpattern = [];
            
            
            for j = 1:length(newStr2)
                myFolder = strcat(Classification_folder,'\',newStr2{j});
                
                
                testingpattern = newStr2{j};
                
                
                for i = 1:length(filelist1)
                    namep = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    val = app.LabelimagesCheckBox.Value;
                    
                    namep2 = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    outputFullFileName_new = strcat(myFolder, '\',newStr2{j},'_', File_listings_transposed{i,1});


                    File_holding = [];
                    if contains(namep,testingpattern,'IgnoreCase',true)
                        
                        if contains(namep,testingpattern2,'IgnoreCase',true)
                            
                        else
                            
                            if contains(namep,'.mat')
                                
                                if val
                                    copyfile(namep, outputFullFileName_new);
                                    
                                else
                                    
                                    copyfile(namep,myFolder);
                                end
                            end
                        end
                    end
                end
                myFolder = [];
            end
            
            
            image_for_size = join([File_listings_transposed{1,2},'\',File_listings_transposed{1,1}]);
            
            d = load(image_for_size);
            f = fields(d);
            data = d.(f{1})(:,:,:);
            data = uint16(data);
            dim2 = size(data);
            
            
            if length(dim2)==2
                number_of_channels = 1;
            else
                number_of_channels = dim2(3);
            end


            Iterations_num =  app.LearningIterationsEditField.Value;
            Iterations_num2 = str2num(Iterations_num);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Feed images to imageDatastore
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            imageFolder = Classification_folder;
            
            
            %imageDatastore is used to pass images to the network without
            %having to load all images into memory
            
            imds = imageDatastore(imageFolder, 'LabelSource', 'foldernames',...
                'IncludeSubfolders',true,'FileExtensions','.mat', 'ReadFcn',@matReader);
            
            
            %Determine number of classes
            numClasses = numel(categories(imds.Labels));


            imdsTrain = imds;
            
            %Specify size of images


            x_pixel_size=dim2(1);
            y_pixel_size=dim2(2);
            
            
            % LOAD RESNET50
            net = resnet50;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Modify ResNet50 for our dataset
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Extract the layer graph from the trained network and plot the layer graph.
            lgraph = layerGraph(net);


            net.Layers(1)
            disp(x_pixel_size)
            disp(y_pixel_size)
            disp(number_of_channels)


            % New input layer
            new_input_layer = [imageInputLayer([x_pixel_size y_pixel_size number_of_channels],'Name','input')]
            lgraph = replaceLayer(lgraph,'input_1', new_input_layer);
            % New 2 channel conv layer
            new_conv1_layer = ...
                convolution2dLayer([3,3],64,'Stride', [1,1] , 'Padding', [1,1,1,1],'Name','new_conv1');
            
            lgraph = replaceLayer(lgraph,'conv1', new_conv1_layer);
            
            
            % Replacing last three layers for transfer learning / retraining
            lgraph = removeLayers(lgraph, {'ClassificationLayer_fc1000','fc1000_softmax','fc1000','avg_pool'});
            % Define layers
            numClasses = numel(categories(imdsTrain.Labels));
            newLayers = [
                averagePooling2dLayer(2,'Stride',1,'Name','avg_pool_4_4')
                fullyConnectedLayer(numClasses,'Name','fc','WeightLearnRateFactor',10,'BiasLearnRateFactor',10)
                softmaxLayer('Name','softmax')
                classificationLayer('Name','classoutput')];
            lgraph = addLayers(lgraph,newLayers);
            
            
            lgraph = connectLayers(lgraph,'activation_49_relu','avg_pool_4_4');


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Plot training progress
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            function stop=savetrainingplot(info,app)
                
                valuetraining = info.TrainingAccuracy;
                xx = info.Iteration
                if length(xx) ==1 && length(valuetraining) ==1
                    
                    
                    plot(app.UIAxes,xx,valuetraining,'--k','Marker','.','MarkerSize' ,14);
                    
                    
                    hold(app.UIAxes,'on')

                    
                end

                pause(0.5)
                
                
            end
            
            inputSize=[ x_pixel_size; y_pixel_size; number_of_channels]';
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandRotation',[0 360], ...
                'RandXReflection',true, ...
                'RandYReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange)
            augimdsTrain = augmentedImageDatastore(inputSize,imdsTrain,'DataAugmentation',imageAugmenter);
            
            
            % Set training options
            
            options = trainingOptions('sgdm', ...
                'MiniBatchSize',64, ...
                'MaxEpochs',Iterations_num2, ... % was 6
                'LearnRateSchedule', 'piecewise', ...
                'InitialLearnRate',1e-3, ...
                'LearnRateDropFactor',0.9, ...
                'LearnRateDropPeriod',50, ...
                'ValidationFrequency',3000, ...
                'ValidationPatience',Inf, ...
                'Verbose',false, ...
                'OutputFcn',@(traininfo)savetrainingplot(traininfo,app),...
                'ExecutionEnvironment','cpu')


            % Set a time and date stamp for the network
            modelDateTime = datestr(now,'dd-mmm-yyyy-HH-MM-SS');
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Train network
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [trainedNet, traininfo] = trainNetwork(augimdsTrain,lgraph,options);
            close all
            figure
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Saving model
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Name model according to number of training classes
            if ~exist(output_directory, 'dir')
                mkdir(output_directory)
            end
            
            numClassesformodel = num2str(numClasses);
            name = strcat('Deepometry-Trained_net_', modelDateTime, '_',numClassesformodel,'.mat');
            
            fullnameandpath = fullfile(output_directory,'\',name);
            
            
            name2 = strcat(output_directory,'\',name);
            save(name2,'trainedNet');
            val = app.LabelimagesCheckBox.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Remove temporary folder if checkbox is not ticked
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if val
                
            else
                rmdir(temporary_location,'s')
            end


        end

        % Button pushed function: PredictButton
        function PredictButtonPushed2(app, event)
            image_directory =  app.InputlocationEditField_5.Value;
            output_directory = app.OutputlocationEditField_5.Value;
            model_directory1 =app.ModellocationEditField.Value;
            model_directory = string(model_directory1);
            Training_location = app.InputlocationEditField_4.Value;
            temporary_location = string(app.TemporaryfolderEditField_4.Value);
            temporary_location2 = string(app.TemporaryfolderEditField.Value);
            
            %Create a folder to store output if it does not already exist.
            
            if ~exist(output_directory, 'dir')
                mkdir(output_directory)
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare data depending on chosen labelling to be applied
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if ~exist( temporary_location, 'dir')
                mkdir( temporary_location);
                
            end
            if isempty(app.InputlocationEditField_5.Value)
                h=errordlg('Please state the location of the preprocessed images','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.OutputlocationEditField_5.Value)
                h=errordlg('Please state where to store all outputs','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.ModellocationEditField.Value)
                h=errordlg('Please state the location of your fully trained model','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.TemporaryfolderEditField_4.Value)
                h=errordlg('Please state where to store a temporary folder of reorganised images','Error');
                set(h, 'WindowStyle', 'modal');
            end


            val2 = app.UnannotateddataCheckBox.Value;


            %Make output directory to store fully trained model
            
            if isempty(app.TargetClassificationsDropDown_2.Value)
                h=errordlg('You have not selected classifications to be applied to your images','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            Groupingtouse = app.TargetClassificationsDropDown_2.Value;
            Groupingtouse = string(Groupingtouse);
            disp(Groupingtouse);
            
            if ~exist( temporary_location, 'dir')
                mkdir( temporary_location);
                
            end


            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = image_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            newStr = extractAfter(Groupingtouse,":");
            disp(newStr)
            newStrfirst = extractBefore(Groupingtouse,":");
            
            
            MM = split(newStr,',');
            newStr2 = strip(MM);
            newStr2(newStr2=="")=[];


            Classification_folder = strcat(temporary_location,'\','Current_Classifications_',newStrfirst);
            
            
            Classification_folder=strrep(Classification_folder,'\\','\');
            Classification_folder  = string(Classification_folder );
            disp(Classification_folder)
            
            if ~exist(Classification_folder, 'dir')
                mkdir(Classification_folder);
                
            end
            disp(newStr2)
            
            for j = 1:length(newStr2)
                Classification_folder_subfolder = strcat(Classification_folder,'\',newStr2{j});
                disp(Classification_folder_subfolder)
                if ~exist(Classification_folder_subfolder, 'dir')
                    mkdir(Classification_folder_subfolder);
                end
                
            end
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            AA = [];
            File_holding = [];
            testingpattern2 = strcat('\Current_Classifications_');
            testingpattern = [];
            
            
            for j = 1:length(newStr2)
                myFolder = strcat(Classification_folder,'\',newStr2{j});
                
                
                testingpattern = newStr2{j};
                
                
                for i = 1:length(filelist1)
                    namep = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    val = app.LabelimagesCheckBox.Value;
                    
                    namep2 = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    outputFullFileName_new = strcat(myFolder, '\',newStr2{j},'_', File_listings_transposed{i,1});
                    
                    
                    disp('nowfile')
                    disp(namep)
                    File_holding = [];
                    if contains(namep,testingpattern,'IgnoreCase',true)
                        
                        if contains(namep,testingpattern2,'IgnoreCase',true)
                            
                        else
                            
                            if contains(namep,'.mat')
                                
                                if val
                                    copyfile(namep, outputFullFileName_new);
                                    
                                else
                                    
                                    copyfile(namep,myFolder)
                                end
                            end
                        end
                    end
                end
                myFolder = [];
            end
            if app.UnannotateddataCheckBox.Value == 1
                imageFolder = image_directory;
                
                
            else
                imageFolder = temporary_location;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Reclassify original training dataset
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if isempty(app.TargetClassificationsDropDown_2.Value)
                h=errordlg('You have not selected classifications that were applied to your images during training','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.TemporaryfolderEditField.Value)
                disp('true')
                imageFolder2 = Training_location;
                
            else
                Groupingtouse = app.TargetClassificationsDropDown.Value;
                Groupingtouse = string(Groupingtouse);
                disp(Groupingtouse);
                
                if ~exist( temporary_location2, 'dir')
                    mkdir( temporary_location2);
                    
                end


                newStr = [];
                File_directory = [];
                filelist = [];
                rootdir = Training_location;
                
                filelist1 = dir(fullfile(rootdir, '**\*.*'));
                filelist1 = filelist1(~[filelist1.isdir]);
                
                newStr = extractAfter(Groupingtouse,":");
                disp(newStr)
                newStrfirst = extractBefore(Groupingtouse,":");
                
                
                MM = split(newStr,',');
                newStr2 = strip(MM);
                newStr2(newStr2=="")=[];


                Classification_folder = strcat(temporary_location2,'\','Current_Classifications_',newStrfirst);
                
                
                Classification_folder=strrep(Classification_folder,'\\','\');
                Classification_folder  = string(Classification_folder );
                disp(Classification_folder)
                
                if ~exist(Classification_folder, 'dir')
                    mkdir(Classification_folder);
                    
                end
                disp(newStr2)
                
                for j = 1:length(newStr2)
                    Classification_folder_subfolder = strcat(Classification_folder,'\',newStr2{j});
                    disp(Classification_folder_subfolder)
                    if ~exist(Classification_folder_subfolder, 'dir')
                        mkdir(Classification_folder_subfolder);
                    end
                    
                end
                
                File_listings = struct2cell(filelist1);
                File_listings_transposed = File_listings';
                newStr = [];
                
                File_directory = File_listings_transposed(:,2);
                
                files = File_listings_transposed(:,1);
                
                AA = [];
                File_holding = [];
                testingpattern2 = strcat('\Current_Classifications_');
                testingpattern = [];
                
                
                for j = 1:length(newStr2)
                    myFolder = strcat(Classification_folder,'\',newStr2{j});
                    
                    
                    testingpattern = newStr2{j};
                    
                    
                    for i = 1:length(filelist1)
                        namep = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                        val = app.LabelimagesCheckBox.Value;
                        
                        namep2 = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                        outputFullFileName_new = strcat(myFolder, '\',newStr2{j},'_', File_listings_transposed{i,1});
                        
                        
                        disp('nowfile')
                        disp(namep)
                        File_holding = [];
                        if contains(namep,testingpattern,'IgnoreCase',true)
                            
                            if contains(namep,testingpattern2,'IgnoreCase',true)
                                
                            else
                                
                                if contains(namep,'.mat')
                                    
                                    if val
                                        copyfile(namep, outputFullFileName_new);
                                        
                                    else
                                        
                                        copyfile(namep,myFolder)
                                    end
                                end
                            end
                        end
                    end
                    myFolder = [];
                end


                imageFolder2 = temporary_location2;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare data and load correct
            %fully trained network
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Use datastore to assess both evaluation data and original
            %training data


            imds = imageDatastore(imageFolder, 'LabelSource', 'foldernames',...
                'IncludeSubfolders',true,'FileExtensions','.mat', 'ReadFcn',@matReader);
            
            imds2 = imageDatastore(imageFolder2, 'LabelSource', 'foldernames',...
                'IncludeSubfolders',true,'FileExtensions','.mat', 'ReadFcn',@matReader);
            
            
            %define size of images
            rootdir = image_directory;
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            
            image_for_size = join([File_listings_transposed{1,2},'\',File_listings_transposed{1,1}]);
            disp(image_for_size)
            d = load(image_for_size);
            f = fields(d);
            data = d.(f{1})(:,:,:);
            data = uint16(data);
            dim2 = size(data);
            disp('size')
            disp(length(dim2))
            
            if length(dim2)==2
                number_of_channels = 1;
            else
                number_of_channels = dim2(3);
            end
            disp('overall')
            disp(number_of_channels)


            % Load most recently trained model with the
            % correct number of classifications
            
            numClassesformodel2 = numel(categories(imds2.Labels));
            disp(numClassesformodel2)
            
            
            %Extract all .mat files trained with correct number of classifications
            % from the model location
            
            ext = strcat(string(numClassesformodel2),'.mat');
            Files=dir(fullfile(model_directory,'*.mat'));
            
            M = length(Files)
            
            if M == 0
                h=errordlg('Make sure that there is a fully trained model stored at the directory specified','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            Namefiles = [];
            Selectedfiles = [];
            for i = 1:length(Files)
                Holding = Files(i).name;
                disp(Files(i).name)
                disp(ext)
                TF = contains(Holding,ext);
                disp(TF)
                if TF>0
                    Selectedfiles{i} = Holding;
                else
                    Selectedfiles{i} = '0';
                    
                end
            end
            AA22 = find(matches(Selectedfiles,'0'));
            Selectedfiles(AA22) = [];
            patterns = [];
            for j = 1:length(Selectedfiles)
                patterns = Selectedfiles{j}
                M = size(Files);
                %
                for k = 1:M(1)
                    AA = find(matches(Files(k).name,patterns))
                    
                    % Determine the date of training
                    
                    if length(AA)>0
                        disp(Files(1).date)
                        DD{j} = Files(k).date;
                        EE{j} = Files(k).name;
                    end
                end
                
                
                patterns = [];
            end
            
            %Select most recently trained model
            
            DD2 = DD;
            V = split(DD2,' ');
            if length(DD2)>1
                New1 = datenum(V(:,:,1))
                [a,b] = max(New1);
                [anew,bnew] = find(New1 ==a);
                New2 = [];
                New22 = [];
                if length(anew)>1
                    New2 = replace(V(:,:,2),':','');
                    for m = 1:length(New2)
                        New22(m) = str2num(New2{m});
                    end
                    testingvals = [];
                    for t = 1:length(anew)
                        testingvals(t) = New22(anew(t));
                    end
                    [a2,b2] = max(testingvals);
                    
                    index = anew(b2);
                    
                else
                    index = anew;
                    
                end
                nametouse = EE{index};
            else
                nametouse = EE;
            end


            ss = strcat(model_directory,'\',nametouse);
            disp(ss)
            tf = isa(ss,'string');
            disp(tf)
            load(ss,'trainedNet')
            [YPred,probs] = classify(trainedNet,imds);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare data and load correct
            %fully trained network
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Use model to classify images
            
            
            val = app.UnannotateddataCheckBox.Value;


            %If user has indicated that images are unannotated
            
            if val
                
                
                model_directory =app.ModellocationEditField.Value;
                
                
                if ~exist(output_directory, 'dir')
                    mkdir(output_directory)
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Prepare data and load correct
                %fully trained network
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %Use datastore to assess both evaluation data and original
                %training data


                %Saved predicted classes for all images.
                
                name = 'Preditions.csv';
                fullnameandpath1 = strcat(output_directory,'\',name);
                
                YPred = removecats(YPred);
                csvwrite(fullnameandpath1,'YPred');
                
                %Plot and save histogram showing distribution of the various
                %classes predicted to be present in your data by model
                
                name = 'Histogramplot.png';
                fullnameandpath2 = strcat(output_directory,'\',name);
                YY2 = grp2idx(YPred);
                figure
                h = histogram(categorical(cellstr(YPred)),categorical(unique(imds2.Labels)))
                fi=gcf;
                
                
                set(gca,'TickLabelInterpreter','none')


                saveas(gcf,fullnameandpath2)
                
            else
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Use data to evaluate model
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %Compare predicted labels with actual images labels to find accuracy
                
                accuracy = mean(YPred == imds.Labels);
                
                %Save accuracy value to specified output location
                
                name = 'accuracyvalues.csv';
                fullnameandpath1 = strcat(output_directory,'\',name);
                csvwrite(fullnameandpath1,'accuracy');
                
                %Create a confusion plot and save this to specified output location
                
                imds.Labels = removecats(imds.Labels);
                YPred = removecats(YPred);
                hh = plotconfusion(imds.Labels,YPred);
                vv = 'confusion_matrix.png';
                fullnameandpath2 = strcat(output_directory,'\',vv);
                fh = gcf;
                
                set(gca,'TickLabelInterpreter','none')
                
                saveas(hh,fullnameandpath2)
                
                %Create a confusion plot of ALL classes, even if these are not present in
                % evaluation data set. Save this to specified output location
                
                fig = figure
                grouporder = categories(imds2.Labels);
                [C,order] = confusionmat(imds.Labels,YPred,'Order',grouporder);
                
                hh2 = confusionchart(C,order);
                ww = 'confusion_matrix_all_classes.png';
                fh = gcf;
                
                %set(gca,'Interpreter','none')
                
                fullnameandpath4 = strcat(output_directory,'\',ww);
                saveas(fig,fullnameandpath4)
                
                %Save predicted classes for each of the evaluation images
                
                name2 = 'Predictions.csv';
                fullnameandpath3 = strcat(output_directory,'\',name2);
                csvwrite(fullnameandpath3,'YPred');
                
            end
            val = app.LabelimagesCheckBox.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Remove temporary folder if checkbox is not ticked
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if val
                
            elseif exist(temporary_location2, 'dir')
                rmdir(temporary_location2,'s')
            end
            
            val2 = app.SavetemporarydirectoryCheckBox.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Remove temporary folder if checkbox is not ticked
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if val2
                
            else
                rmdir(temporary_location,'s')
            end
            

        end

        % Callback function
        function PredictButtonPushed(app, event)


        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            image_directory = app.InputlocationEditField_7.Value;
            model_directory = string(app.ModellocationEditField_3.Value);
            output_directory = app.OutputlocationEditField_7.Value;
            Training_location = app.InputlocationEditField_4.Value;
            temporary_location = app.TemporaryfolderEditField_3.Value;
            temporary_location2 = app.TemporaryfolderEditField.Value;
            
            %Create folder to store all features and plots if this folder
            %does not already exit
            
            if ~exist(output_directory, 'dir')
                mkdir(output_directory)
            end


            if isempty(app.InputlocationEditField_7.Value)
                h=errordlg('You must specify the location of your processed images to extract features from','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            
            if isempty(app.ModellocationEditField_3.Value)
                h=errordlg('Please specify the location of your fully trained model','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            
            if isempty(app.OutputlocationEditField_7.Value)
                h=errordlg('Please specify where to store output plots and extracted features','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            if isempty(app.InputlocationEditField_4.Value)
                h=errordlg('Please specify the location of your training images','Error');
                set(h, 'WindowStyle', 'modal');
            end
            
            
            if isempty(app.TemporaryfolderEditField_3.Value)
                h=errordlg('Please specify where to place a temporary folder to save reorganised images','Error');
                set(h, 'WindowStyle', 'modal');
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Determine which layer to extract features from
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if app.avg_pool_4_4Button.Value
                Layer = 'avg_pool_4_4';
            elseif app.activation_25_reluButton.Value
                Layer = 'activation_25_relu';
            else
                Layer = 'activation_43_relu';
            end
            ccc =  app.ChannelsEditField.Value;
            
            channels =str2num(ccc);
            
            
            if isempty(app.TemporaryfolderEditField_3.Value)
                disp('true')
                image_directory = image_directory;
                
            else
            
            
            Groupingtouse = app.TargetClassificationsDropDown_3.Value;
            Groupingtouse = string(Groupingtouse);
            
            
            if isempty(app.TargetClassificationsDropDown_3.Value)
                h=errordlg('Please select classification labels to be used on your data','Error');
                set(h, 'WindowStyle', 'modal');
            end


            if ~exist( temporary_location, 'dir')
                mkdir( temporary_location);
                
            end


            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = image_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare data depending on chosen labelling to be applied
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            newStr = extractAfter(Groupingtouse,":");
            
            newStrfirst = extractBefore(Groupingtouse,":");
            
            
            MM = split(newStr,',');
            newStr2 = strip(MM);
            newStr2(newStr2=="")=[];


            Classification_folder = strcat(temporary_location,'\','Current_Classifications_',newStrfirst);
            
            
            Classification_folder=strrep(Classification_folder,'\\','\');
            Classification_folder  = string(Classification_folder );
            
            
            if ~exist(Classification_folder, 'dir')
                mkdir(Classification_folder);
                
            end
            
            
            for j = 1:length(newStr2)
                Classification_folder_subfolder = strcat(Classification_folder,'\',newStr2{j});
                disp(Classification_folder_subfolder)
                if ~exist(Classification_folder_subfolder, 'dir')
                    mkdir(Classification_folder_subfolder);
                end
                
            end
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            AA = [];
            File_holding = [];
            testingpattern2 = strcat('\Current_Classifications_');
            testingpattern = [];
            
            
            for j = 1:length(newStr2)
                myFolder = strcat(Classification_folder,'\',newStr2{j});
                
                
                testingpattern = newStr2{j};
                
                
                for i = 1:length(filelist1)
                    namep = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    val = app.LabelimagesCheckBox.Value;
                    
                    namep2 = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    outputFullFileName_new = strcat(myFolder, '\',newStr2{j},'_', File_listings_transposed{i,1});
                    
                    
                    File_holding = [];
                    if contains(namep,testingpattern,'IgnoreCase',true)
                        
                        if contains(namep,testingpattern2,'IgnoreCase',true)
                            
                        else
                            
                            if contains(namep,'.mat')
                                
                                if val
                                    copyfile(namep, outputFullFileName_new);
                                    
                                else
                                    
                                    copyfile(namep,myFolder)
                                end
                            end
                        end
                    end
                end
                myFolder = [];
            end
            image_directory = temporary_location;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare original training data depending on chosen labelling to be applied
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if isempty(app.TemporaryfolderEditField.Value)
                disp('true')
                image_directory2 = Training_location;
               
                
            else
            
            Groupingtouse = app.TargetClassificationsDropDown.Value;
            Groupingtouse = string(Groupingtouse);
            disp(Groupingtouse);
            
            if ~exist( temporary_location2, 'dir')
                mkdir( temporary_location2);
                
            end


            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = Training_location;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            newStr = extractAfter(Groupingtouse,":");
            
            newStrfirst = extractBefore(Groupingtouse,":");
            
            
            MM = split(newStr,',');
            newStr2 = strip(MM);
            newStr2(newStr2=="")=[];


            Classification_folder = strcat(temporary_location2,'\','Current_Classifications_',newStrfirst);
            
            
            Classification_folder=strrep(Classification_folder,'\\','\');
            Classification_folder  = string(Classification_folder );
            
            
            if ~exist(Classification_folder, 'dir')
                mkdir(Classification_folder);
                
            end
            
            
            for j = 1:length(newStr2)
                Classification_folder_subfolder = strcat(Classification_folder,'\',newStr2{j});
                disp(Classification_folder_subfolder)
                if ~exist(Classification_folder_subfolder, 'dir')
                    mkdir(Classification_folder_subfolder);
                end
                
            end
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            AA = [];
            File_holding = [];
            testingpattern2 = strcat('\Current_Classifications_');
            testingpattern = [];
            
            
            for j = 1:length(newStr2)
                myFolder = strcat(Classification_folder,'\',newStr2{j});
                
                
                testingpattern = newStr2{j};
                
                
                for i = 1:length(filelist1)
                    namep = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    val = app.LabelimagesCheckBox.Value;
                    
                    namep2 = join([File_listings_transposed{i,2},'\',File_listings_transposed{i,1}]);
                    outputFullFileName_new = strcat(myFolder, '\',newStr2{j},'_', File_listings_transposed{i,1});
                    
                    
                    disp('nowfile')
                    disp(namep)
                    File_holding = [];
                    if contains(namep,testingpattern,'IgnoreCase',true)
                        
                        if contains(namep,testingpattern2,'IgnoreCase',true)
                            
                        else
                            
                            if contains(namep,'.mat')
                                
                                if val
                                    copyfile(namep, outputFullFileName_new);
                                    
                                else
                                    
                                    copyfile(namep,myFolder)
                                end
                            end
                        end
                    end
                end
                myFolder = [];
            end
            image_directory2 = temporary_location2;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Prepare data and load correct
            %fully trained network
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %Split the data in the same way as it was seperated for
            %training


            imageFolder = image_directory;
            imds = imageDatastore(imageFolder, 'LabelSource', 'foldernames',...
                'IncludeSubfolders',true,'FileExtensions','.mat', 'ReadFcn',@matReader);
            
            
            imdsTrain = imds;
            
            %Define size of images used
            
            rootdir = image_directory;
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            
            File_listings = struct2cell(filelist1);
            File_listings_transposed = File_listings';
            newStr = [];
            
            File_directory = File_listings_transposed(:,2);
            
            files = File_listings_transposed(:,1);
            
            
            image_for_size = join([File_listings_transposed{1,2},'\',File_listings_transposed{1,1}]);
            
            d = load(image_for_size);
            f = fields(d);
            data = d.(f{1})(:,:,:);
            data = uint16(data);
            dim2 = size(data);
            
            
            if length(dim2)==2
                number_of_channels = 1;
            else
                number_of_channels = dim2(3);
            end


            %             number_of_channels = length(channels);
            x_pixel_size = dim2(1);
            y_pixel_size = dim2(1);
            
            %Augment data
            
            inputSize=[ x_pixel_size; y_pixel_size; number_of_channels]';
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandRotation',[0 360], ...
                'RandXReflection',true, ...
                'RandYReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange)


            augimdsTrain = augmentedImageDatastore(inputSize,imdsTrain,'DataAugmentation',imageAugmenter);
            
            
            % Load correct model from which to extract features
            
            
            numClassesformodel2 = numel(categories(imdsTrain.Labels));
            
            ext = strcat(string(numClassesformodel2),'.mat')
            
            %Extract all .mat files stored at model location
            
            Files=dir(fullfile(model_directory,'*.mat'));
            M = length(Files)
            
            if M == 0
                h=errordlg('Make sure that there is a fully trained model stored at the directory specified','Error');
                set(h, 'WindowStyle', 'modal');
            end
            Namefiles = [];
            Selectedfiles = [];
            for i = 1:length(Files)
                Holding = Files(i).name
                TF = contains(Holding,ext)
                if TF>0
                    Selectedfiles{i} = Holding;
                else
                    Selectedfiles{i} = '0';
                    
                end
            end
            AA22 = find(matches(Selectedfiles,'0'));
            Selectedfiles(AA22) = [];
            patterns = [];
            for j = 1:length(Selectedfiles)
                patterns = Selectedfiles{j}
                M = size(Files);
                %
                for k = 1:M(1)
                    AA = find(matches(Files(k).name,patterns))
                    %
                    % Determine the date each model was created
                    
                    if length(AA)>0
                        DD{j} = Files(k).date;
                        EE{j} = Files(k).name;
                    end
                end
                
                
                patterns = [];
            end
            
            % Find most recent model
            
            DD2 = DD;
            V = split(DD2,' ');
            if length(DD2)>1
                New1 = datenum(V(:,:,1))
                [a,b] = max(New1);
                [anew,bnew] = find(New1 ==a);
                New2 = [];
                New22 = [];
                if length(anew)>1
                    New2 = replace(V(:,:,2),':','');
                    for m = 1:length(New2)
                        New22(m) = str2num(New2{m});
                    end
                    testingvals = [];
                    for t = 1:length(anew)
                        testingvals(t) = New22(anew(t));
                    end
                    [a2,b2] = max(testingvals);
                    
                    index = anew(b2);
                    
                else
                    index = anew;
                    
                end
                nametouse = EE{index};
            else
                nametouse = EE;
            end
            
            
            %Load correct model
            
            ss = strcat(model_directory,'\',nametouse);
            
            load(ss, 'trainedNet')
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Generate features from chosen layer of model
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            layer = Layer;
            featuresTrain = activations(trainedNet,augimdsTrain,layer,'OutputAs','rows');
            
            %Generate labels for each of the images so that colour coding
            %may be applied to all plots
            
            Augmented = augimdsTrain.Files;
            Holder = [];
            label = [];
            for i = 1:length(Augmented)
                [a,b,e] = fileparts(Augmented{i});
                Holder{i} = a;
            end
            tf = []
            BB = []
            charArr = cellfun(@num2str, Holder, 'Un', 0 );
            UniqueAugmented = unique (charArr, 'rows');
            for i = 1:length(Augmented)
                BB{i} = Holder{i};
                for j = 1:length(UniqueAugmented)
                    strtesting{j} = UniqueAugmented{j};
                    tf(j) = strcmp(strtesting{j},BB{i})
                    
                end
                
                
                [a,b] = find(tf==1)
                label(i) = b
            end
            vv = label;
            disp(categories(imdsTrain.Labels))
            labs = unique(vv);
            % Define the two colormaps
            cmap1 = jet(length(labs));
            cmap2 = summer(length(labs)) ;
            % Combine them into one tall colormap.
            combinedColorMap = [cmap1; cmap2]
            % Pick 15 rows at random.
            randomRows = randi(size(combinedColorMap, 1), [length(labs), 1])
            % Extract the rows from the combined color map
            randomColors = combinedColorMap(randomRows, :);
            
            labels_graph = categories(imds.Labels);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Plot features and save all output to folder
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Save features extacted as .csv and .mat files
            
            name = 'features.mat';
            fullfilepath = strcat(output_directory,'\',name);
            save(fullfilepath,'featuresTrain');
            
            name2 = 'featuresforumap.csv';
            fullfilepath7 = strcat(output_directory,'\');
            
            
            if ~exist(fullfilepath7, 'dir')
                mkdir(fullfilepath7)
            end
            Locationforcsv2 = strcat(fullfilepath7,name2);
            csvwrite(Locationforcsv2,featuresTrain)
            
            %Create and save basic 2D and 3D tSNE plots of features, colour coded according to
            %classification


            close all
            clf
            
            
            %%%%
            labels_graph = categories(imds.Labels);
            %%%%%
            
            figure(10)
            
            
            Y = tsne(featuresTrain);
            labellen = unique(vv)
            if ~exist(temporary_location, 'dir')
                scatter(Y(:,1),Y(:,2),15,'k','filled')
                            l = legend (labels_graph)
            fi =gcf
            
            set(l, 'Interpreter', 'none')
                
            else
            for i = 1:length(labellen)
                [a,b] = find(vv == i)
                scatter(Y(b,1),Y(b,2),15,'MarkerEdgeColor',randomColors(i,:),...
                    'MarkerFaceColor',randomColors(i,:),...
                    'LineWidth',1.5)
                hold on
                
            end
            l = legend (labels_graph)
            fi =gcf
            
            set(l, 'Interpreter', 'none')
            end


            xlabel('x')
            ylabel('y')
            hold off
            
            vvv = 'tSNEplot.png';
            vvv2 = '3DtSNEplot.png';
            fullfilepath1 = strcat(output_directory,'\',vvv);
            fullfilepath2 = strcat(output_directory,'\',vvv2);
            saveas(fi,fullfilepath1)
            [Y2,loss2] = tsne(featuresTrain,'Algorithm','exact','NumDimensions',3);
            hold off
            
            figure(11)
            
            hold on
            
            %%%%
            labels_graph = categories(imds.Labels);
            %%%%%


            [Y2,loss2] = tsne(featuresTrain,'Algorithm','exact','NumDimensions',3);
            
            if ~exist(temporary_location, 'dir')
                scatter3(Y2(:,1),Y2(:,2),Y2(:,3),15,'k','filled')
                            l = legend (labels_graph)
                fi2 =gcf
                
                set(l, 'Interpreter', 'none')
                
            else
            
            for i = 1:length(labellen)
                [a,b] = find(vv == i)
                scatter3(Y2(b,1),Y2(b,2),Y2(b,3),15,'MarkerEdgeColor',randomColors(i,:),...
                    'MarkerFaceColor',randomColors(i,:),...
                    'LineWidth',1.5)
                hold on
                
            end
            end
            l = legend (labels_graph)
            
            set(l, 'Interpreter', 'none')
            fi2 =gcf
            
            xlabel('x')
            ylabel('y')
            zlabel('z')
            hold off
            
            
            saveas(fi2,fullfilepath2)
            
            %Create and save basic 3D PCA plot of features, colour coded according to
            %classification
            [coeff,score,latent,tsquared,explained] = pca(featuresTrain);
            hold off
            figure(12)
            
            hold on
            if ~exist(temporary_location, 'dir')
                scatter3(score(:,1),score(:,2),score(:,3),15,'k','filled')
                  l = legend (labels_graph)
                  l = legend('test_line');
                  set(l, 'Interpreter', 'none')
                    
                  fi3 =gcf
                
            else
            
            
            for i = 1:length(labellen)
                [a,b] = find(vv == i)
                scatter3(score(b,1),score(b,2),score(b,3),15,'MarkerEdgeColor',randomColors(i,:),...
                    'MarkerFaceColor',randomColors(i,:),...
                    'LineWidth',1.5)
                hold on
                
            end
            l = legend (labels_graph)
            l = legend('test_line');
            set(l, 'Interpreter', 'none')
            
            fi3 =gcf
            end


            xlabel('1st Principal Component')
            ylabel('2nd Principal Component')
            zlabel('3rd Principal Component')
            nameplot = 'PCA3Dplot.png';
            nameplot2 = 'PCA3Dplot.fig';
            fullfilepath3 = strcat(output_directory,'\',nameplot);
            fullfilepath3b = strcat(output_directory,'\',nameplot2);
            
            l = legend(labels_graph);
            
            set(l, 'Interpreter', 'none')
            saveas(fi3,fullfilepath3)
            saveas(fi3,fullfilepath3b)
            
            %If paths to UMAP have been added this section is executed and
            %a basic UMAP plot is generated


            close all
            
            holder_fold = pwd
            totalpath = 'umapDistribution\umap';
            path1 = totalpath;
            addpath(path1)
            holder_fold = pwd
            totalpath = 'umapDistribution\util';
            path2 = totalpath;
            addpath(path2)
            holder_fold = pwd
            totalpath = 'umapDistribution\umap\umap.jar';
            path3 = totalpath;
            addpath(path3)


            %
            addpath(path1)
            addpath(path2)
            javaaddpath(path3)
            name2 = 'featuresforumap.csv';
            fullfilepath4 = strcat(output_directory,'\','Dataforumap\');
            
            
            if ~exist(fullfilepath4, 'dir')
                mkdir(fullfilepath4)
            end
            Locationforcsv = strcat(fullfilepath4,name2);
            
            fullfilepath5 = strcat(output_directory,'\','Dataforumap\');
            if ~exist(fullfilepath5, 'dir')
                mkdir(fullfilepath5)
            end
            
            name3 = 'template2D.mat';
            Locationfortemplate = strcat(fullfilepath5,name3);
            csvwrite(Locationforcsv,featuresTrain)
            
            
            figure(5)
            h1 = run_umap(Locationforcsv,'save_template_file',Locationfortemplate)
            nameplot1 = 'UMAP2Dplot.fig';
            fullfilepath7 = strcat(output_directory,'\',nameplot1);
            savefig(fullfilepath7)
            
            figure(6)
            h2 = run_umap(Locationforcsv, 'n_components',3,'save_template_file',Locationfortemplate)
            nameplot2 = 'UMAP3Dplot.fig';
            fullfilepath6 = strcat(output_directory,'\',nameplot2);
            savefig(fullfilepath6)
            close all
            
            holder_fold = pwd
            totalpath = 'PHATE-master\PHATE-master\Matlab';
            path4 = totalpath;
            addpath(path4)
            
            Y = phate(double(featuresTrain));
            close all
            figure(7)
            hold on
            
            if ~exist(temporary_location, 'dir')
                scatter(Y(:,1),Y(:,2),15,'k','filled')
                 l = legend (labels_graph)
            
                 set(l, 'Interpreter', 'none')
            
                 fi4 = gcf
            %
                
            else
            
            for i = 1:length(labellen)
                [a,b] = find(vv == i)
                scatter(Y(b,1),Y(b,2),15,'MarkerEdgeColor',randomColors(i,:),...
                    'MarkerFaceColor',randomColors(i,:),...
                    'LineWidth',1.5)
                hold on
                
            end
            l = legend (labels_graph)
            
            set(l, 'Interpreter', 'none')
            
            fi4 = gcf
            %
            end


            xlabel('First dimension')
            ylabel('Second dimension')


            nameplot3 ='PHATE2Dplot.png';
            fullfilepath7 = strcat(output_directory,'\',nameplot3);
            saveas(fi4,fullfilepath7)


            Y = phate(double(featuresTrain),'ndim',3);
            if ~exist(temporary_location, 'dir')
                scatter3(Y(:,1),Y(:,2),Y(:,3),15,'k','filled')
                 l = legend (labels_graph)
            
                set(l, 'Interpreter', 'none')
                
                fi5 = gcf
            %
                
            else
            close all
            figure(8)
            hold on
            
            for i = 1:length(labellen)
                [a,b] = find(vv == i)
                scatter3(Y(b,1),Y(b,2),Y(b,3),15,'MarkerEdgeColor',randomColors(i,:),...
                    'MarkerFaceColor',randomColors(i,:),...
                    'LineWidth',1.5)
                hold on
                
            end
            l = legend (labels_graph)
            
            set(l, 'Interpreter', 'none')
            
            fi5 =gcf
            %
            end


            xlabel('First dimension')
            ylabel('Second dimension')
            zlabel('Third dimension')
            h2 = run_umap(Locationforcsv, 'n_components',3,'save_template_file',Locationfortemplate)
            nameplot4 = 'PHATE3Dplot.png';
            fullfilepath8 = strcat(output_directory,'\',nameplot4);
            saveas(fi5,fullfilepath8)


           rmdir(temporary_location,'s')


        end

        % Selection changed function: ExtractfeaturestovisualizeButtonGroup
        function ExtractfeaturestovisualizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExtractfeaturestovisualizeButtonGroup.SelectedObject;
            
        end

        % Button pushed function: RetrieveButton
        function RetrieveButtonPushed(app, event)
            output_directory = app.InputlocationEditField_4.Value;
            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = output_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            filelist = dir(fullfile(rootdir, '**\*.*'));
            filelist2 = dir(fullfile(rootdir, '**\*.*'));
            filelist2(ismember( {filelist2.name}, {'.'})) = [];  %remove . and ..
            filelist2(ismember( {filelist2.name}, {'..'})) = [];
            filelist2(contains( {filelist2.name}, {'.mat'})) = [];
            filelist(ismember( {filelist.name}, {'.'})) = [];  %remove . and ..
            filelist(ismember( {filelist.name}, {'..'})) = [];
            filelist(contains( {filelist.name}, {'.mat'})) = [];
            
            
            filelist(contains( {filelist.folder}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.folder}, {'Current_Classifications_'})) = [];
            filelist(contains( {filelist.name}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.name}, {'Current_Classifications_'})) = [];
            
            AA = [];
            for i = 1:length(filelist)
                namep = filelist(i).name;
                AA{i} = namep;
            end


            VV =[];
            
            for i = 1:length(AA)
                pattern1 = ' ';
                pattern2 = '-';
                pattern3 = '_';
                TF1 = contains(AA{i},pattern1);
                TF2 = contains(AA{i},pattern2);
                TF3 = contains(AA{i},pattern3);
                
                [a,b] = find(TF1==1);
                [a2,b2] = find(TF2==1);
                [a3,b3] = find(TF3==1);


                L1 = length(b);
                L2 = length(b2);
                L3 = length(b3);
                 if L1 == 0 && L2 == 0 && L3 == 0
                    h=errordlg('Please ensure folders have a prefix for classification purposes','Error');
                    set(h, 'WindowStyle', 'modal');
                 end
                    
            
                if L1 == 0;
                    b = 1000;
                end
                if L2 == 0;
                    b2 = 1000;
                end
                if L3 ==0;
                    b3 = 1000;
                end
                S = [b,b2,b3];
                
                [SS,m] = min(S);
                if m == 1
                    newStr{i} = extractBefore(AA{i}," ");
                elseif m == 2
                    newStr{i} = extractBefore(AA{i},"-");
                else
                    newStr{i} = extractBefore(AA{i},"_");
                end
                a = newStr;
                [dummy, ia] = unique(lower(a));
                VV = a(ia);
                
                 for jj = 1:length(VV)
                     pattern = VV{jj};
                     for ii = 1:length(filelist2);
                         TF(ii,jj) = contains(filelist2(ii).name,pattern,'IgnoreCase',true);
                     	
                     end
            
                 end
                
                
            end
            %Include case insenstive
            Overall = [];
            final_list = [];
            
            [a,b] =find(TF==1);
            BB = [];
            DD = cell(1,max(b));
            for kk = 1:max(b)
                final_list = [];
               
                [a2,b2] = find(b==kk);
                for jj = 1:length(b2)
                    number = a(a2);
                    BB = [];
                    for mm = 1:length(number)
                    final_list{mm} = filelist2(number(mm)).name;
                    if contains(final_list{mm},'.mat','IgnoreCase',true)
                    
                        BB = BB;
                    else
                    BB = [BB, ' ', filelist2(number(mm)).name, ' ,',];
                    end
                    end


                end
                DD{kk} =strcat(VV{kk},':',BB);


            end


            app.TargetClassificationsDropDown.Items = DD
        end

        % Button pushed function: RetrieveButton_2
        function RetrieveButton_2Pushed(app, event)
             output_directory = app.InputlocationEditField_5.Value;
            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = output_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            filelist = dir(fullfile(rootdir, '**\*.*'));
            filelist2 = dir(fullfile(rootdir, '**\*.*'));
            filelist2(ismember( {filelist2.name}, {'.'})) = [];  %remove . and ..
            filelist2(ismember( {filelist2.name}, {'..'})) = [];
            filelist2(contains( {filelist2.name}, {'.mat'})) = [];
            filelist(ismember( {filelist.name}, {'.'})) = [];  %remove . and ..
            filelist(ismember( {filelist.name}, {'..'})) = [];
            filelist(contains( {filelist.name}, {'.mat'})) = [];
            
            
            filelist(contains( {filelist.folder}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.folder}, {'Current_Classifications_'})) = [];
            filelist(contains( {filelist.name}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.name}, {'Current_Classifications_'})) = [];
            
            AA = [];
            for i = 1:length(filelist)
                namep = filelist(i).name;
                AA{i} = namep;
            end


            VV =[];
            
            for i = 1:length(AA)
                pattern1 = ' ';
                pattern2 = '-';
                pattern3 = '_';
                TF1 = contains(AA{i},pattern1);
                TF2 = contains(AA{i},pattern2);
                TF3 = contains(AA{i},pattern3);
                
                [a,b] = find(TF1==1);
                [a2,b2] = find(TF2==1);
                [a3,b3] = find(TF3==1);


                L1 = length(b);
                L2 = length(b2);
                L3 = length(b3);
                 if L1 == 0 && L2 == 0 && L3 == 0
                    h=errordlg('Please ensure folders have a prefix for classification purposes','Error');
                    set(h, 'WindowStyle', 'modal');
                 end
                    
            
                if L1 == 0;
                    b = 1000;
                end
                if L2 == 0;
                    b2 = 1000;
                end
                if L3 ==0;
                    b3 = 1000;
                end
                S = [b,b2,b3];
                
                [SS,m] = min(S);
                if m == 1
                    newStr{i} = extractBefore(AA{i}," ");
                elseif m == 2
                    newStr{i} = extractBefore(AA{i},"-");
                else
                    newStr{i} = extractBefore(AA{i},"_");
                end
                a = newStr;
                [dummy, ia] = unique(lower(a));
                VV = a(ia);
                
                 for jj = 1:length(VV)
                     pattern = VV{jj};
                     for ii = 1:length(filelist2);
                         TF(ii,jj) = contains(filelist2(ii).name,pattern,'IgnoreCase',true);
                     	
                     end
            
                 end
                
                
            end
            %Include case insenstive
            Overall = [];
            final_list = [];
            
            [a,b] =find(TF==1);
            BB = [];
            DD = cell(1,max(b));
            for kk = 1:max(b)
                final_list = [];
               
                [a2,b2] = find(b==kk);
                for jj = 1:length(b2)
                    number = a(a2);
                    BB = [];
                    for mm = 1:length(number)
                    final_list{mm} = filelist2(number(mm)).name;
                    if contains(final_list{mm},'.mat','IgnoreCase',true)
                    
                        BB = BB;
                    else
                    BB = [BB, ' ', filelist2(number(mm)).name, ' ,',];
                    end
                    end


                end
                DD{kk} =strcat(VV{kk},':',BB);
                
                % whatever other code you have
            % populate the drop-down
            
            end


            app.TargetClassificationsDropDown_2.Items = DD
        end

        % Button pushed function: RetrieveButton_3
        function RetrieveButton_3Pushed(app, event)
             output_directory = app.InputlocationEditField_7.Value;
            newStr = [];
            File_directory = [];
            filelist = [];
            rootdir = output_directory;
            
            filelist1 = dir(fullfile(rootdir, '**\*.*'));
            filelist1 = filelist1(~[filelist1.isdir]);
            filelist = dir(fullfile(rootdir, '**\*.*'));
            filelist2 = dir(fullfile(rootdir, '**\*.*'));
            filelist2(ismember( {filelist2.name}, {'.'})) = [];  %remove . and ..
            filelist2(ismember( {filelist2.name}, {'..'})) = [];
            filelist2(contains( {filelist2.name}, {'.mat'})) = [];
            filelist(ismember( {filelist.name}, {'.'})) = [];  %remove . and ..
            filelist(ismember( {filelist.name}, {'..'})) = [];
            filelist(contains( {filelist.name}, {'.mat'})) = [];
            
            
            filelist(contains( {filelist.folder}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.folder}, {'Current_Classifications_'})) = [];
            filelist(contains( {filelist.name}, {'Current_Classifications_'})) = [];
            filelist2(contains( {filelist2.name}, {'Current_Classifications_'})) = [];
            
            AA = [];
            for i = 1:length(filelist)
                namep = filelist(i).name;
                AA{i} = namep;
            end


            VV =[];
            
            for i = 1:length(AA)
                pattern1 = ' ';
                pattern2 = '-';
                pattern3 = '_';
                TF1 = contains(AA{i},pattern1);
                TF2 = contains(AA{i},pattern2);
                TF3 = contains(AA{i},pattern3);
                
                [a,b] = find(TF1==1);
                [a2,b2] = find(TF2==1);
                [a3,b3] = find(TF3==1);


                L1 = length(b);
                L2 = length(b2);
                L3 = length(b3);
                 if L1 == 0 && L2 == 0 && L3 == 0
                    h=errordlg('Please ensure folders have a prefix for classification purposes','Error');
                    set(h, 'WindowStyle', 'modal');
                 end
                    
            
                if L1 == 0;
                    b = 1000;
                end
                if L2 == 0;
                    b2 = 1000;
                end
                if L3 ==0;
                    b3 = 1000;
                end
                S = [b,b2,b3];
                
                [SS,m] = min(S);
                if m == 1
                    newStr{i} = extractBefore(AA{i}," ");
                elseif m == 2
                    newStr{i} = extractBefore(AA{i},"-");
                else
                    newStr{i} = extractBefore(AA{i},"_");
                end
                a = newStr;
                [dummy, ia] = unique(lower(a));
                VV = a(ia);
                
                 for jj = 1:length(VV)
                     pattern = VV{jj};
                     for ii = 1:length(filelist2);
                         TF(ii,jj) = contains(filelist2(ii).name,pattern,'IgnoreCase',true);
                     	
                     end
            
                 end
                
                
            end
            %Include case insenstive
            Overall = [];
            final_list = [];
            
            [a,b] =find(TF==1);
            BB = [];
            DD = cell(1,max(b));
            for kk = 1:max(b)
                final_list = [];
               
                [a2,b2] = find(b==kk);
                for jj = 1:length(b2)
                    number = a(a2);
                    BB = [];
                    for mm = 1:length(number)
                    final_list{mm} = filelist2(number(mm)).name;
                    if contains(final_list{mm},'.mat','IgnoreCase',true)
                    
                        BB = BB;
                    else
                    BB = [BB, ' ', filelist2(number(mm)).name, ' ,',];
                    end
                    end


                end
                DD{kk} =strcat(VV{kk},':',BB);
                
                % whatever other code you have
            % populate the drop-down
            
            end


            app.TargetClassificationsDropDown_3.Items = DD
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Training progress')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Training accuracy')
            app.DeepometryLabel = uilabel(app.UIFigure);
            app.DeepometryLabel.Text = 'Deepometry  ';
            app.PreprocessingLabel = uilabel(app.UIFigure);
            app.PreprocessingLabel.Text = '1. Preprocessing';
            app.MontageSizeEditField_3Label = uilabel(app.UIFigure);
            app.MontageSizeEditField_3Label.Text = 'Montage Size:';
            app.MontageSizeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ModeltrainingLabel = uilabel(app.UIFigure);
            app.ModeltrainingLabel.Text = '2. Model training ';
            app.aPredictionLabel = uilabel(app.UIFigure);
            app.aPredictionLabel.Text = '3a. Prediction';
            app.bFeatureextractionLabel = uilabel(app.UIFigure);
            app.bFeatureextractionLabel.Text = {'3b. Feature extraction '; ''};
            app.InputlocationEditFieldLabel = uilabel(app.UIFigure);
            app.InputlocationEditFieldLabel.Text = {'Input location:'; ''};
            app.InputlocationEditField = uieditfield(app.UIFigure, 'text');
            app.OutputlocationEditFieldLabel = uilabel(app.UIFigure);
            app.OutputlocationEditFieldLabel.Text = {'Output location:'; ''};
            app.OutputlocationEditField = uieditfield(app.UIFigure, 'text');
            app.ParseButton = uibutton(app.UIFigure, 'push');
            app.ParseButton.ButtonPushedFcn = createCallbackFcn(app, @ParseButtonPushed, true);
            app.ParseButton.Tooltip = {'Preprocessing images will convert them into matrices stored as .mat files. '; ''; 'To action this step you should:'; ''; '1. Input the location of images'; '2. Specify the output location (where the processed files should be stored)'; '3. Input the frame size of your images'; '4. Input which channels should be analysed'; '5. *If you wish to montage images specify the size of the montage here OTHERWISE PLEASE LEAVE BLANK'; '6. Input the split to apply to your data'};
            app.ParseButton.Text = 'Parse';
            app.InputlocationEditField_4Label = uilabel(app.UIFigure);
            app.InputlocationEditField_4Label.Text = {'Input location:'; ''};
            app.InputlocationEditField_4 = uieditfield(app.UIFigure, 'text');
            app.OutputlocationEditField_4Label = uilabel(app.UIFigure);
            app.OutputlocationEditField_4Label.Text = {'Output location:'; ''};
            app.OutputlocationEditField_4 = uieditfield(app.UIFigure, 'text');
            app.InputlocationEditField_5Label = uilabel(app.UIFigure);
            app.InputlocationEditField_5Label.Text = {'Input location:'; ''};
            app.InputlocationEditField_5 = uieditfield(app.UIFigure, 'text');
            app.OutputlocationEditField_5Label = uilabel(app.UIFigure);
            app.OutputlocationEditField_5Label.Text = {'Output location:'; ''};
            app.OutputlocationEditField_5 = uieditfield(app.UIFigure, 'text');
            app.ModellocationEditFieldLabel = uilabel(app.UIFigure);
            app.ModellocationEditFieldLabel.Text = {'Model location:'; ''};
            app.ModellocationEditField = uieditfield(app.UIFigure, 'text');
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Tooltip = {'Model training will train the network using your images and whichever classification specified.'; ''; 'To action this step you should:'; ''; '1. Input the location of images'; '2. Specify the output location (where to store the final fully trained model)'; '3. Input the location where a temporary folder to store the reorganised images can be created. These images will be stored in folders depending on their class. THIS FOLDER WILL BE DELETED AFTER THE MODEL HAS BEEN TRAINED'; '4. The number of learning iterations to use when training the model'; '5. *If you wish to create a dataset where each image  is labelled according to your selection, check the ''Label images'' checkbox. THIS WILL CREATE A PERMENANT FOLDER WHICH WILL NOT BE DELETED AND CAN BE USED FURTHER'};
            app.TrainButton.Text = 'Train';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Picture3.jpg';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed2, true);
            app.PredictButton.Tooltip = {'Evaluate your model using hold out data or predict the classifications of unlabelled images.  '; ''; 'To action this step you should:'; ''; '1. Specify the location of images that you wish to use'; '2. Analyse  images based on directory structure and determine which label to use'; '3. Specify a temporary folder for reorganised images THIS FOLDER WILL BE DELETED ONCE IMAGES ARE CLASSIFIED'; '4. Input the location of the original images used to train the model, this will ensure that the correct model is recreated'; '5. You should specify the labels used to classify the original images '; '6. Input a location where a temporary folder for the reorganised training images can be stored. '; '7. Input a directory to store all outputs from this step, these may include a confusion matrix plot, histogram, classification accuracy etc.'; '8. Input the location of the fully trained model'; '9. Check box if you are providing unlabelled data'; ''};
            app.PredictButton.Text = 'Predict';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'Picture4.png';
            app.ExtractfeaturestovisualizeButtonGroup = uibuttongroup(app.UIFigure);
            app.ExtractfeaturestovisualizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExtractfeaturestovisualizeButtonGroupSelectionChanged, true);
            app.ExtractfeaturestovisualizeButtonGroup.Title = 'Extract features to visualize:';
            app.activation_25_reluButton = uiradiobutton(app.ExtractfeaturestovisualizeButtonGroup);
            app.activation_25_reluButton.Text = 'activation_25_relu';
            app.activation_25_reluButton.Value = true;
            app.activation_43_reluButton = uiradiobutton(app.ExtractfeaturestovisualizeButtonGroup);
            app.activation_43_reluButton.Text = {'activation_43_relu'; ''};
            app.avg_pool_4_4Button = uiradiobutton(app.ExtractfeaturestovisualizeButtonGroup);
            app.avg_pool_4_4Button.Text = 'avg_pool_4_4';
            app.InputlocationEditField_7Label = uilabel(app.UIFigure);
            app.InputlocationEditField_7Label.Text = {'Input location:'; ''};
            app.InputlocationEditField_7 = uieditfield(app.UIFigure, 'text');
            app.OutputlocationEditField_7Label = uilabel(app.UIFigure);
            app.OutputlocationEditField_7Label.Text = {'Output location:'; ''};
            app.OutputlocationEditField_7 = uieditfield(app.UIFigure, 'text');
            app.ModellocationEditField_3Label = uilabel(app.UIFigure);
            app.ModellocationEditField_3Label.Text = {'Model location:'; ''};
            app.ModellocationEditField_3 = uieditfield(app.UIFigure, 'text');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'Picture3.png';
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Tooltip = {'Use images to extract features learnt by your netword during training, visualise these high dimensional features in lower dimensions.'; ''; 'To action this step you should:'; ''; '1. Specify the location of images that you wish to extract features for'; '2. Analyse  images based on directory structure and determine which labels to use'; '3. Specify a temporary folder for reorganised images THIS FOLDER WILL BE DELETED ONCE IMAGES ARE CLASSIFIED'; '4. Specify the location of original images used to train your model and the classes that were used.'; 'THIS STEP ENSURES THAT THE MODEL IS RECONSTRUCEED IN THE CORRRECT WAY'; '5. A temporary folder to store the reorganised training images'; '6. Input where you wish to store all outputs from this step, these include all features extracted and some basic plots in 2 and 3D. '; '7. Use radio buttons to specify the layer of the network from which you wish to extract features'; '8. The location of the fully trained model'; ''};
            app.ExtractButton.Text = 'Extract';
            app.ChannelsLabel = uilabel(app.UIFigure);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsEditField = uieditfield(app.UIFigure, 'text');
            app.LearningIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.LearningIterationsEditFieldLabel.Text = {'Learning Iterations:'; ''};
            app.LearningIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.ImageSource = 'firstone (1).jpg';
            app.FrameSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FrameSizeEditFieldLabel.Text = {'Frame Size:'; ''};
            app.FrameSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetClassificationsDropDownLabel = uilabel(app.UIFigure);
            app.TargetClassificationsDropDownLabel.Text = 'Target Classifications:';
            app.TargetClassificationsDropDown = uidropdown(app.UIFigure);
            app.RetrieveButton = uibutton(app.UIFigure, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPushed, true);
            app.RetrieveButton.Tooltip = {'Analysing your dataset will output all classification options that you can train your model against depending on your folder structure.'; ''; 'To action this step you should:'; ''; '1. Input the location of images'; ''};
            app.RetrieveButton.Text = 'Retrieve';
            app.TemporaryfolderEditFieldLabel = uilabel(app.UIFigure);
            app.TemporaryfolderEditFieldLabel.Text = 'Temporary folder:';
            app.TemporaryfolderEditField = uieditfield(app.UIFigure, 'text');
            app.LabelimagesCheckBox = uicheckbox(app.UIFigure);
            app.LabelimagesCheckBox.Text = 'Label images';
            app.TrainHoldoutLabel = uilabel(app.UIFigure);
            app.TrainHoldoutLabel.Text = 'Train/Hold-out:';
            app.TrainHoldoutEditField = uieditfield(app.UIFigure, 'text');
            app.TargetClassificationsDropDown_2Label = uilabel(app.UIFigure);
            app.TargetClassificationsDropDown_2Label.Text = 'Target Classifications:';
            app.TargetClassificationsDropDown_2 = uidropdown(app.UIFigure);
            app.RetrieveButton_2 = uibutton(app.UIFigure, 'push');
            app.RetrieveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButton_2Pushed, true);
            app.RetrieveButton_2.Tooltip = {'Analysing your dataset will output all classification options that you can train your model against depending on your folder structure.'; ''; 'To action this step you should:'; ''; '1. Input the location of images'};
            app.RetrieveButton_2.Text = 'Retrieve';
            app.UnannotateddataCheckBox = uicheckbox(app.UIFigure);
            app.UnannotateddataCheckBox.Text = 'Unannotated data?';
            app.TargetClassificationsDropDown_3Label = uilabel(app.UIFigure);
            app.TargetClassificationsDropDown_3Label.Text = 'Target Classifications: ';
            app.TargetClassificationsDropDown_3 = uidropdown(app.UIFigure);
            app.TemporaryfolderEditField_3Label = uilabel(app.UIFigure);
            app.TemporaryfolderEditField_3Label.Text = 'Temporary folder:';
            app.TemporaryfolderEditField_3 = uieditfield(app.UIFigure, 'text');
            app.RetrieveButton_3 = uibutton(app.UIFigure, 'push');
            app.RetrieveButton_3.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButton_3Pushed, true);
            app.RetrieveButton_3.Tooltip = {'Analysing your dataset will output all classification options that you can train your model against depending on your folder structure.'; ''; 'To action this step you should:'; ''; '1. Input the location of images'};
            app.RetrieveButton_3.Text = {'Retrieve'; ''};
            app.TemporaryfolderEditField_4Label = uilabel(app.UIFigure);
            app.TemporaryfolderEditField_4Label.Text = 'Temporary folder:';
            app.TemporaryfolderEditField_4 = uieditfield(app.UIFigure, 'text');
            app.SavetemporarydirectoryCheckBox = uicheckbox(app.UIFigure);
            app.SavetemporarydirectoryCheckBox.Text = 'Save temporary directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_fordeepometrymac2_PART4m14vvllRECENT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end